classdef cdr_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        fCountLabel               matlab.ui.control.Label
        SelectFrameSpinner        matlab.ui.control.Spinner
        SelectSpecificFrameSpinnerLabel  matlab.ui.control.Label
        distALabel                matlab.ui.control.Label
        distCLabel                matlab.ui.control.Label
        DistancefromcamLabel      matlab.ui.control.Label
        ActualLabel               matlab.ui.control.Label
        CalculatedLabel           matlab.ui.control.Label
        zPosALabel                matlab.ui.control.Label
        yPosALabel                matlab.ui.control.Label
        xPosALabel                matlab.ui.control.Label
        SettingsColumnPanel       matlab.ui.container.Panel
        SelectVolleySpinner       matlab.ui.control.Spinner
        SelectReturnLabel         matlab.ui.control.Label
        LoadmatButton             matlab.ui.control.Button
        BlenderLinkButton         matlab.ui.control.Button
        SelectServeSpinner        matlab.ui.control.Spinner
        SelectServeLabel          matlab.ui.control.Label
        ShiftAccuracyGraphButton  matlab.ui.control.Button
        DepthAccuracyGraphButton  matlab.ui.control.Button
        CalibrateCamButton        matlab.ui.control.Button
        BallColorColorPicker      matlab.ui.control.ColorPicker
        BallColorLabel            matlab.ui.control.Label
        CourtColorColorPicker     matlab.ui.control.ColorPicker
        CourtColorLabel           matlab.ui.control.Label
        inBoundsLabel             matlab.ui.control.Label
        inBoundsLEDLabel          matlab.ui.control.Label
        zPosCLabel                matlab.ui.control.Label
        ZheightLabel              matlab.ui.control.Label
        SwitchViewModeButton      matlab.ui.control.StateButton
        yPosCLabel                matlab.ui.control.Label
        YwidthLabel               matlab.ui.control.Label
        ViewModeLabel             matlab.ui.control.Label
        ViewingModeLabel          matlab.ui.control.Label
        xPosCLabel                matlab.ui.control.Label
        XlengthLabel              matlab.ui.control.Label
        InstantReplayButton       matlab.ui.control.Button
        PositionLabel             matlab.ui.control.Label
        yPxRLabel                 matlab.ui.control.Label
        xPxRLabel                 matlab.ui.control.Label
        CenterPxRLabel            matlab.ui.control.Label
        yPxLLabel                 matlab.ui.control.Label
        xPxLLabel                 matlab.ui.control.Label
        CenterPxLLabel            matlab.ui.control.Label
        UIAxesRight               matlab.ui.control.UIAxes
        UIAxesLeft                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        % blender link initialization parameters
        server_ip   = '127.0.0.1';   % ip address of the unity server
        server_port = 55001;         % server port of the unity server

        % camera settings and variables
        b = .060;      % baseline [mm]
        f = .03;       % focal length [mm]
        ps = .00006;   % pixel size [mm]

        % 3d space coordinate variables, in meters
        disparity;   % disparity from camera [m]
        dC;   % calculated distance from camera [m]
        xC;   % calculated x position of the ball (length) [m]
        yC;   % calculated y position of the ball (height) [m]
        zC;   % calculated z position of the ball (depth) [m]
        dA;   % actual distance from camera [m]
        xA;   % actual x position of the ball (length) [m]
        yA;   % actual y position of the ball (height) [m]
        zA;   % actual z position of the ball (depth) [m]

        % 2d space coordinate variables, in pixels
        xLeft;
        yLeft;
        xRight;
        yRight;

        % saved versions of camera images (video recording)
        leftImage = {};
        rightImage = {};
        leftFrame = {};
        rightFrame = {};

        % selection for specific serve/volley movement data or recording
        serveSpinnerVal;
        volleySpinnerVal;

        % selection for ball and court color selection
        courtColor;
        ballColor;

        % select between raw footage and processed frame
        % 0 = raw camera footage, 1 = processed video frame
        showProcFrame = 0;
        
        % this variable changes framerate
        % 1 / 24fps * 1000ms = 41.67ms/frame
        framesTimeMs = 41;

        % variable for frame visualization selection
        % maximum value for currentFrame depends on size of video
        currentFrame = 1;

        % image processing variables
        threshold = .3;   % binarization threshold
        minSize = 50;     % minimum size[px] of detected objects
        se = strel('disk',3);  % structure element to fill gaps in image

    end


    methods (Access = private) 
        
        % generate a file name for blender link and recording
        function [selectedSwing,selectedSwingRec] = selectServeVolley(app)
            if app.serveSpinnerVal ~= 0
                swingType = "serve";
                swingNum = num2str(app.SelectServeSpinner.Value);
            elseif app.volleySpinnerVal ~= 0
                swingType = "volley";
                swingNum = num2str(app.SelectVolleySpinner.Value);
            else
                % prints a warning to command window some kind of warning, itll default to serve1 for now
                disp('Please select a serve or volley. Serve 1 has been automatically selected.')
                swingType = "serve";
                swingNum = "1";
            end
            path = "./datFiles/";
            extIn = ".dat";
            extOut = ".mat";
            record = "Rec";

            selectedSwing = strcat(path,swingType,swingNum,extIn);
            selectedSwingRec = strcat(path,swingType,swingNum,record,extOut);
        end

        % MAS IMAGE PROCESSING ROUTINE FROM LABS 3+4
        % convert image to grayscale, then black and white
        % remove small objects, close gaps, and fill holes
        % return edited image and roundest object's centroid 
        function [frame] = processImage(app,frame)

            frame = rgb2gray(frame);                 % convert to grayscale
            frame = imbinarize(frame,app.threshold); % convert to black and white
            frame = bwareaopen(frame,app.minSize);   % remove small objects
            frame = imclose(frame,app.se);           % close small gaps in boundaries
            frame = imfill(frame,'holes');           % fill holes in objects
        end


        % return the centroid location of the detected circular object
        function [xPixel,yPixel] = findCircularObject(app,frame)

            [B,L] = bwboundaries(frame,"noholes");   % find ext. object boundaries

            % determine the circularity and centroid of each object
            stats = regionprops(L,'Circularity','Centroid');

            % determine the roundest object and store its centroid
            roundestObj = 0;
            for k = 1:length(B)
                if stats(k).Circularity > roundestObj
                    roundestObj = stats(k).Circularity;
                    xPixel = stats(k).Centroid(1,1);
                    yPixel = stats(k).Centroid(1,2);
                end
            end
        end


        % calculate the disparity, and x,y,z coordinates, based on a frame-counter parameter
        function calculatePosition(app,count)

            % initialize camera pixel dimensions
            xNumPix = 752;         % total num pixels in x direction of sensor [px]
            yNumPix = 480;         % total num pixels in y direction of sensor [px]
            cxLeft = xNumPix/2;    % left camera x center [px]
            cyLeft = yNumPix/2;    % left camera y center [px]
            cxRight = xNumPix/2;   % right camera x center [px]
            cyRight = yNumPix/2;   % right camera x center [px]

            app.disparity(count) = (abs((app.yLeft(count)-cyLeft)-(app.yRight(count)-cyRight))*app.ps);   % disparity [m]
            app.zC(count) = (app.b*app.f)/app.disparity(count);                           % depth [m]
            app.xC(count) = app.zC(count) * ((app.xLeft(count)-cxLeft) * app.ps)/app.f;   % length [m]
            app.yC(count) = app.zC(count) * ((app.yLeft(count)-cyLeft) * app.ps)/app.f;   % height [m]
        end


        % update the centroid pixel location labels for Lcam and Rcam
        function updateCentroidPxLabel(app, count)
            app.xPxLLabel.Text = num2str(app.xLeft(count))
            app.yPxLLabel.Text = num2str(app.yLeft(count))
            app.xPxRLabel.Text = num2str(app.xRight(count))
            app.yPxRLabel.Text = num2str(app.yRight(count))
        end


        % update the calculated (and actual) coordinates of the sphere
        % displayed actual coordinates may not be kept
        % actual distance needs to be calculated from datFiles
        function updatePositionLabel(app,count)
            app.xPosCLabel.Text = num2str(app.xC(count))
            app.yPosCLabel.Text = num2str(app.yC(count))
            app.zPosCLabel.Text = num2str(app.zC(count))
            app.xPosALabel.Text = num2str(app.xA(count))
            app.yPosALabel.Text = num2str(app.yA(count))
            app.zPosALabel.Text = num2str(app.zA(count))
        end


        % update the calculated (and actual) distances of the sphere
        % perform calculations and update labels
        function updateDistLabel(app,count)
            
            calcDist = sqrt((app.xC(count)^2 + (app.yC(count)^2) + app.zC(count)-18)^2);
            actDist  = sqrt((app.xA(count)^2 + (app.yA(count)^2) + app.zA(count)-18)^2);
            app.distCLabel.Text = num2str(calcDist);
            app.distALabel.Text = num2str(actDist);
        end


        % pseudo-code function since hardware was never connected
        function updateLED(app)

            % if received bit says the led is green
                % app.inBoundsLEDLabel.BackgroundColor = "green";
                % app.inBoundsLabel.Text = "Ball is currently in bounds";
            % elseif received bit says the led is red
                % app.inBoundsLabel.Text = "Ball is currently out of bounds";
                % app.inBoundsLEDLabel.BackgroundColor = "red";
                % wait 1s
                % app.inBoundsLEDLabel.BackgroundColor = "white";
                % wait 1s
        end


        % plot a processed image to an axis
        % draw a boundary around/display circularity of all objects
        % plot the centroid of the roundest object
        function plotObjects(app,frame,axis)

            [B,L] = bwboundaries(frame,"noholes");   % find ext. object boundaries

            % determine the circularity and centroid of each object
            stats = regionprops(L,'Circularity','Centroid');

            roundestObj = 0;
            for k = 1:length(B)

                boundary = B{k};   % get (x,y) boundary coordinates for label 'k'
                circ_value = stats(k).Circularity;   % obtain circularity for label 'k'
            
                imagesc(axis,label2rgb(L,@jet,[.5 .5 .5]))
                hold(axis,'on')
                for objects = 1:length(B)
                  boundary = B{objects};
                  plot(axis,boundary(:,2),boundary(:,1),"w",LineWidth=2)
                end
            
                % Display the circularity results
                circ_string = sprintf('%2.2f',circ_value);
                text(axis,boundary(1,2)-35,boundary(1,1)+13,circ_string,Color='y', ...
                    FontSize=14,FontWeight='bold');
            
                % determine the roundest object and store centroid
                if stats(k).Circularity > roundestObj
                    roundestObj = stats(k).Circularity;
                    roundestObjNum = k;
                end
            
                [app.xCentroid(frame),app.yCentroid(frame)] = stats(roundestObjNum).Centroid;
                plot(axis,app.xCentroid(frame),app.yCentroid(frame),'ko');
            end
        end


        % plot the accuracy on the below UIAccuracy axes
        function plotAccuracy(app)
            xNumPix = 752;         % total num pixels in x direction of sensor [px]
            yNumPix = 480;         % total num pixels in y direction of sensor [px]
            cxLeft = xNumPix/2;    % left camera x center [px]
            cyLeft = yNumPix/2;    % left camera y center [px]
            cxRight = xNumPix/2;   % right camera x center [px]
            
            % Calculate depth
            app.d = (abs((app.xLeft-cxLeft)-(app.xRight-cxRight))*app.ps)   % disparity [mm]
            app.Z = (app.b*app.f)./app.d                                % depth [mm]
            app.X = app.Z .* ((app.xLeft-cxLeft) * app.ps)/app.f        % length [mm]
            app.Y = app.Z .* ((app.yLeft-cyLeft) * app.ps)/app.f        % height [mm]

            % plot the distance vs accuracy from 3 to 5 meters away
            dist = 3:.1:5;                              % actual distance [m]
            calcDist = app.Z/1000;                      % calculated dist. [m] 
            accuracy = 100-(calcDist-dist)./dist*100    % dist. accuracy [%]
            %plot(app.UIAccuracy,dist,accuracy);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % axis(app.UIAxesLeft,'off')
            % axis(app.UIAxesRight,'off')
            % axis(app.UIAxesLeft,'tight')
            % axis(app.UIAxesRight,'tight')
            app.ViewModeLabel.Text = "Camera View";
            app.SwitchViewModeButton.Text = "Switch to Processed View";
        end

        % Button pushed function: BlenderLinkButton
        function BlenderLinkButtonPushed(app, event)
            [selectedSwing,selectedSwingRec] = app.selectServeVolley();

            client = tcpclient(app.server_ip,app.server_port,"Timeout",20);
            fprintf(1,"Connected to server\n");
        
            % resolution of virtual camera
            width = 752;
            height = 480;

            % relative link works when Current Folder is CDR_GUI
            swing = readmatrix(selectedSwing);
            
            dataSize = size(swing);
            numFrames = dataSize(1);
            
            videoFrameNum = 1:app.framesTimeMs:numFrames % number of frame from original video
            frameCount = length(videoFrameNum)           % number of frame in sequence

            app.fCountLabel.Text = "/ " + num2str(frameCount);
            app.SelectFrameSpinner.Value = 0;
            app.SelectFrameSpinner.Limits = [1 frameCount];

            for count = 1:(frameCount)
                x = swing(videoFrameNum(count),1);
                y = swing(videoFrameNum(count),3);
                z = swing(videoFrameNum(count),2);
                app.xA(count) = x;
                app.yA(count) = y;
                app.zA(count) = z;
            
                blenderLink(client,width,height,x,y,z,0,0,0,"tennisBall");

                app.leftImage{count} = blenderLink(client,width,height,.03,0,18,0,0,90,'Camera');
                imagesc(app.UIAxesLeft,app.leftImage{count});
                axis(app.UIAxesLeft,'off')
                axis(app.UIAxesLeft,'tight')
                [app.leftFrame{count}] = app.processImage(app.leftImage{count});
                [app.xLeft(count),app.yLeft(count)] = app.findCircularObject(app.leftFrame{count});
            
                app.rightImage{count} = blenderLink(client,width,height,-.03,0,18,0,0,90,'Camera');
                imagesc(app.UIAxesRight,app.rightImage{count});
                axis(app.UIAxesRight,'off')
                axis(app.UIAxesRight,'tight')
                [app.rightFrame{count}] = app.processImage(app.rightImage{count});
                [app.xRight(count),app.yRight(count)] = app.findCircularObject(app.rightFrame{count});
            
                %X NEED TO GET DELTA T
                % receive x,y,z,deltaT
                % time = time + deltaT;   % intended to ignore blender delays

                app.updateCentroidPxLabel(count)
                app.calculatePosition(count);
                app.updatePositionLabel(count);
                app.updateDistLabel(count)
                app.SelectFrameSpinner.Value = count;
            end

            leftImage = app.leftImage;
            rightImage = app.rightImage;
            ballX = app.xA;
            ballY = app.yA;
            ballZ = app.zA;

            save(selectedSwingRec,"leftImage","rightImage","ballX","ballY","ballZ")
        end

        % Button pushed function: LoadmatButton
        function LoadmatButtonPushed(app, event)
            [selectedSwing,selectedSwingRec] = app.selectServeVolley();
            
            load(selectedSwingRec,"leftImage","rightImage","ballX","ballY","ballZ")
            app.leftImage = leftImage;
            app.rightImage = rightImage;
            app.xA = ballX;
            app.yA = ballY;
            app.zA = ballZ;

            imagesc(app.UIAxesLeft,app.leftImage{1});
            imagesc(app.UIAxesRight,app.rightImage{1});
            axis(app.UIAxesLeft,'off')
            axis(app.UIAxesLeft,'tight')
            axis(app.UIAxesRight,'off')
            axis(app.UIAxesRight,'tight')

            app.currentFrame = 1;
            app.SelectFrameSpinner.Value = 1;
            app.SelectFrameSpinner.Limits = [1 length(app.leftImage)];

            app.fCountLabel.Text = "/ " + num2str(length(app.leftImage));

            for count = 1:length(app.leftImage)
                [app.leftFrame{count}] = app.processImage(app.leftImage{count});
                [app.xLeft(count),app.yLeft(count)] = app.findCircularObject(app.leftFrame{count});
                [app.rightFrame{count}] = app.processImage(app.rightImage{count});
                [app.xRight(count),app.yRight(count)] = app.findCircularObject(app.rightFrame{count});

                app.updateCentroidPxLabel(count);
                app.calculatePosition(count);
                app.updatePositionLabel(count);
                app.updateDistLabel(count);
                app.SelectFrameSpinner.Value = count;
            end

            %app.plotAccuracy();
        end

        % Value changed function: SwitchViewModeButton
        function SwitchViewModeButtonValueChanged(app, event)
            app.showProcFrame = app.SwitchViewModeButton.Value;

            if app.showProcFrame == 0
                % display unprocessed footage
                app.ViewModeLabel.Text = "Camera View";
                app.SwitchViewModeButton.Text = "Switch to Processed View";
                imagesc(app.UIAxesLeft,app.leftImage{app.currentFrame});
                imagesc(app.UIAxesRight,app.rightImage{app.currentFrame});
                axis(app.UIAxesLeft,'off')
                axis(app.UIAxesLeft,'tight')
                axis(app.UIAxesRight,'off')
                axis(app.UIAxesRight,'tight')
            else
                % display processed images
                app.ViewModeLabel.Text = "Processed View";
                app.SwitchViewModeButton.Text = "Switch to Camera View";
                imagesc(app.UIAxesLeft,app.leftFrame{app.currentFrame});
                imagesc(app.UIAxesRight,app.rightFrame{app.currentFrame});
                axis(app.UIAxesLeft,'off')
                axis(app.UIAxesLeft,'tight')
                axis(app.UIAxesRight,'off')
                axis(app.UIAxesRight,'tight')
            end
        end

        % Value changed function: SelectServeSpinner
        function SelectServeSpinnerValueChanged(app, event)
            app.serveSpinnerVal = app.SelectServeSpinner.Value;
            app.SelectVolleySpinner.Value = 0;
            app.volleySpinnerVal = 0;
        end

        % Value changed function: SelectVolleySpinner
        function SelectVolleySpinnerValueChanged(app, event)
            app.volleySpinnerVal = app.SelectVolleySpinner.Value;
            app.SelectServeSpinner.Value = 0;
            app.serveSpinnerVal = 0;
        end

        % Callback function: CourtColorColorPicker
        function CourtColorColorPickerValueChanged(app, event)
            % honestly not entirely sure how color codes would have been
            %     sent from matlab to the snickerdoodle
            app.courtColor = app.CourtColorColorPicker.Value
        end

        % Callback function: BallColorColorPicker
        function BallColorColorPickerValueChanged(app, event)
            % honestly not entirely sure how color codes would have been
            %     sent from matlab to the snickerdoodle
            app.ballColor = app.BallColorColorPicker.Value
        end

        % Value changed function: SelectFrameSpinner
        function SelectFrameSpinnerValueChanged(app, event)
            app.currentFrame = app.SelectFrameSpinner.Value;
            
            app.showProcFrame = app.SwitchViewModeButton.Value;

            if app.showProcFrame == 0
                % display unprocessed footage
                imagesc(app.UIAxesLeft,app.leftImage{app.currentFrame});
                imagesc(app.UIAxesRight,app.rightImage{app.currentFrame});
                axis(app.UIAxesLeft,'off')
                axis(app.UIAxesLeft,'tight')
                axis(app.UIAxesRight,'off')
                axis(app.UIAxesRight,'tight')
            else
                % display processed images
                imagesc(app.UIAxesLeft,app.leftFrame{app.currentFrame});
                imagesc(app.UIAxesRight,app.rightFrame{app.currentFrame});
                axis(app.UIAxesLeft,'off')
                axis(app.UIAxesLeft,'tight')
                axis(app.UIAxesRight,'off')
                axis(app.UIAxesRight,'tight')
            end

            app.updateCentroidPxLabel(app.currentFrame)
            app.calculatePosition(app.currentFrame);
            app.updatePositionLabel(app.currentFrame);
            app.updateDistLabel(app.currentFrame)
        end

        % Button pushed function: InstantReplayButton
        function InstantReplayButtonPushed(app, event)
            pause("on");
            for count = 1:length(app.leftImage)
                app.currentFrame = count;
                app.SelectFrameSpinner.Value = count;
                if app.showProcFrame == 0
                    % display unprocessed footage
                    imagesc(app.UIAxesLeft,app.leftImage{app.currentFrame});
                    imagesc(app.UIAxesRight,app.rightImage{app.currentFrame});
                    axis(app.UIAxesLeft,'off')
                    axis(app.UIAxesLeft,'tight')
                    axis(app.UIAxesRight,'off')
                    axis(app.UIAxesRight,'tight')
                else
                    % display processed images
                    imagesc(app.UIAxesLeft,app.leftFrame{app.currentFrame});
                    imagesc(app.UIAxesRight,app.rightFrame{app.currentFrame});
                    axis(app.UIAxesLeft,'off')
                    axis(app.UIAxesLeft,'tight')
                    axis(app.UIAxesRight,'off')
                    axis(app.UIAxesRight,'tight')
                end

                app.updateCentroidPxLabel(count);
                app.calculatePosition(count);
                app.updatePositionLabel(count);
                app.updateDistLabel(count);

                pause(.041); % pause for 41ms, similar to 24fps
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesLeft = uiaxes(app.UIFigure);
            title(app.UIAxesLeft, 'Left Camera')
            app.UIAxesLeft.XTick = [];
            app.UIAxesLeft.YTick = [];
            app.UIAxesLeft.YTickLabel = '';
            app.UIAxesRight = uiaxes(app.UIFigure);
            title(app.UIAxesRight, 'Right Camera')
            app.UIAxesRight.XTick = [];
            app.UIAxesRight.YTick = [];
            app.CenterPxLLabel = uilabel(app.UIFigure);
            app.CenterPxLLabel.Text = 'Center Px (x,y):';
            app.xPxLLabel = uilabel(app.UIFigure);
            app.xPxLLabel.Text = 'xPxL';
            app.yPxLLabel = uilabel(app.UIFigure);
            app.yPxLLabel.Text = 'yPxL';
            app.CenterPxRLabel = uilabel(app.UIFigure);
            app.CenterPxRLabel.Text = 'Center Px (x,y):';
            app.xPxRLabel = uilabel(app.UIFigure);
            app.xPxRLabel.Text = 'xPxR';
            app.yPxRLabel = uilabel(app.UIFigure);
            app.yPxRLabel.Text = 'yPxR';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.InstantReplayButton = uibutton(app.UIFigure, 'push');
            app.InstantReplayButton.ButtonPushedFcn = createCallbackFcn(app, @InstantReplayButtonPushed, true);
            app.InstantReplayButton.Text = 'Instant Replay';
            app.XlengthLabel = uilabel(app.UIFigure);
            app.XlengthLabel.Text = 'X (length):';
            app.xPosCLabel = uilabel(app.UIFigure);
            app.xPosCLabel.Text = 'xPosC';
            app.ViewingModeLabel = uilabel(app.UIFigure);
            app.ViewingModeLabel.Text = 'Viewing Mode:';
            app.ViewModeLabel = uilabel(app.UIFigure);
            app.ViewModeLabel.Text = 'ViewMode';
            app.YwidthLabel = uilabel(app.UIFigure);
            app.YwidthLabel.Text = 'Y (width):';
            app.yPosCLabel = uilabel(app.UIFigure);
            app.yPosCLabel.Text = 'yPosC';
            app.SwitchViewModeButton = uibutton(app.UIFigure, 'state');
            app.SwitchViewModeButton.ValueChangedFcn = createCallbackFcn(app, @SwitchViewModeButtonValueChanged, true);
            app.SwitchViewModeButton.Text = 'Switch View Mode';
            app.ZheightLabel = uilabel(app.UIFigure);
            app.ZheightLabel.Text = 'Z (height):';
            app.zPosCLabel = uilabel(app.UIFigure);
            app.zPosCLabel.Text = 'zPosC';
            app.inBoundsLEDLabel = uilabel(app.UIFigure);
            app.inBoundsLEDLabel.Text = 'inBoundsLED';
            app.inBoundsLabel = uilabel(app.UIFigure);
            app.inBoundsLabel.Text = 'Ball is currently in bounds';
            app.SettingsColumnPanel = uipanel(app.UIFigure);
            app.SettingsColumnPanel.Title = 'Settings Column';
            app.CourtColorLabel = uilabel(app.SettingsColumnPanel);
            app.CourtColorLabel.Text = 'Court Color:';
            app.CourtColorColorPicker = uicolorpicker(app.SettingsColumnPanel);
            app.CourtColorColorPicker.ValueChangedFcn = createCallbackFcn(app, @CourtColorColorPickerValueChanged, true);
            app.BallColorLabel = uilabel(app.SettingsColumnPanel);
            app.BallColorLabel.Text = 'Ball Color:';
            app.BallColorColorPicker = uicolorpicker(app.SettingsColumnPanel);
            app.BallColorColorPicker.ValueChangedFcn = createCallbackFcn(app, @BallColorColorPickerValueChanged, true);
            app.CalibrateCamButton = uibutton(app.SettingsColumnPanel, 'push');
            app.CalibrateCamButton.Enable = 'off';
            app.CalibrateCamButton.Text = 'Calibrate Cam';
            app.DepthAccuracyGraphButton = uibutton(app.SettingsColumnPanel, 'push');
            app.DepthAccuracyGraphButton.Enable = 'off';
            app.DepthAccuracyGraphButton.Text = 'Depth Accuracy Graph';
            app.ShiftAccuracyGraphButton = uibutton(app.SettingsColumnPanel, 'push');
            app.ShiftAccuracyGraphButton.Enable = 'off';
            app.ShiftAccuracyGraphButton.Text = 'Shift Accuracy Graph';
            app.SelectServeLabel = uilabel(app.SettingsColumnPanel);
            app.SelectServeLabel.Text = 'Select Serve:';
            app.SelectServeSpinner = uispinner(app.SettingsColumnPanel);
            app.SelectServeSpinner.Limits = [0 5];
            app.SelectServeSpinner.RoundFractionalValues = 'on';
            app.SelectServeSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectServeSpinnerValueChanged, true);
            app.BlenderLinkButton = uibutton(app.SettingsColumnPanel, 'push');
            app.BlenderLinkButton.ButtonPushedFcn = createCallbackFcn(app, @BlenderLinkButtonPushed, true);
            app.BlenderLinkButton.Text = 'Blender Link';
            app.LoadmatButton = uibutton(app.SettingsColumnPanel, 'push');
            app.LoadmatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmatButtonPushed, true);
            app.LoadmatButton.Text = 'Load .mat';
            app.SelectReturnLabel = uilabel(app.SettingsColumnPanel);
            app.SelectReturnLabel.Text = 'Select Volley:';
            app.SelectVolleySpinner = uispinner(app.SettingsColumnPanel);
            app.SelectVolleySpinner.Limits = [0 5];
            app.SelectVolleySpinner.RoundFractionalValues = 'on';
            app.SelectVolleySpinner.ValueChangedFcn = createCallbackFcn(app, @SelectVolleySpinnerValueChanged, true);
            app.xPosALabel = uilabel(app.UIFigure);
            app.xPosALabel.Text = 'xPosA';
            app.yPosALabel = uilabel(app.UIFigure);
            app.yPosALabel.Text = 'yPosA';
            app.zPosALabel = uilabel(app.UIFigure);
            app.zPosALabel.Text = 'zPosA';
            app.CalculatedLabel = uilabel(app.UIFigure);
            app.CalculatedLabel.Text = 'Calculated';
            app.ActualLabel = uilabel(app.UIFigure);
            app.ActualLabel.Text = 'Actual';
            app.DistancefromcamLabel = uilabel(app.UIFigure);
            app.DistancefromcamLabel.Text = {'Distance:'; '(from cam)'};
            app.distCLabel = uilabel(app.UIFigure);
            app.distCLabel.Text = 'distC';
            app.distALabel = uilabel(app.UIFigure);
            app.distALabel.Text = 'distA';
            app.SelectSpecificFrameSpinnerLabel = uilabel(app.UIFigure);
            app.SelectSpecificFrameSpinnerLabel.Text = {'Select'; 'Specific'; 'Frame:'};
            app.SelectFrameSpinner = uispinner(app.UIFigure);
            app.SelectFrameSpinner.Limits = [0 1];
            app.SelectFrameSpinner.RoundFractionalValues = 'on';
            app.SelectFrameSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectFrameSpinnerValueChanged, true);
            app.fCountLabel = uilabel(app.UIFigure);
            app.fCountLabel.Text = 'fCount';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cdr_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end