classdef cdr_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ActualLabel                matlab.ui.control.Label
        CalculatedLabel            matlab.ui.control.Label
        zPosLabel_2                matlab.ui.control.Label
        yPosLabel_2                matlab.ui.control.Label
        xPosLabel_2                matlab.ui.control.Label
        SettingsColumnPanel        matlab.ui.container.Panel
        LoadmatButton              matlab.ui.control.Button
        BlenderLinkButton          matlab.ui.control.Button
        SelectReturnSpinner        matlab.ui.control.Spinner
        SelectReturnLabel          matlab.ui.control.Label
        SelectServeSpinner         matlab.ui.control.Spinner
        SelectServeLabel           matlab.ui.control.Label
        ShiftAccuracyGraphButton   matlab.ui.control.Button
        DepthAccuracyGraphButton   matlab.ui.control.Button
        CalibrateCamButton         matlab.ui.control.Button
        CameraConfigurationButton  matlab.ui.control.Button
        BallColorColorPicker       matlab.ui.control.ColorPicker
        BallColorLabel             matlab.ui.control.Label
        CourtColorColorPicker      matlab.ui.control.ColorPicker
        CourtColorLabel            matlab.ui.control.Label
        SelectFrameEditField       matlab.ui.control.NumericEditField
        SelectFrame59Label         matlab.ui.control.Label
        inBoundsLabel              matlab.ui.control.Label
        inBoundsLEDLabel           matlab.ui.control.Label
        zPosLabel                  matlab.ui.control.Label
        ZheightLabel               matlab.ui.control.Label
        SwitchViewingModeRawProcessedButton  matlab.ui.control.StateButton
        yPosLabel                  matlab.ui.control.Label
        YwidthLabel                matlab.ui.control.Label
        ViewModeLabel              matlab.ui.control.Label
        ViewingModeLabel           matlab.ui.control.Label
        xPosLabel                  matlab.ui.control.Label
        XlengthLabel               matlab.ui.control.Label
        SaveRecordingButton        matlab.ui.control.Button
        InstantReplayButton        matlab.ui.control.Button
        PositionLabel              matlab.ui.control.Label
        yPxRLabel                  matlab.ui.control.Label
        xPxRLabel                  matlab.ui.control.Label
        CenterPxRLabel             matlab.ui.control.Label
        yPxLLabel                  matlab.ui.control.Label
        xPxLLabel                  matlab.ui.control.Label
        CenterPxLLabel             matlab.ui.control.Label
        UIAxesRight                matlab.ui.control.UIAxes
        UIAxesLeft                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        % initialization parameters
        server_ip   = '127.0.0.1';   % ip address of the unity server
        server_port = 55001;         % server port of the unity server

        % camera settings and variables
        b = 60      % baseline [mm]
        f = 6       % focal length [mm]
        ps = .006   % pixel size [mm]

        % coordinate variables
        d = {};   % disparity [mm]
        X = {};   % x position of the ball (length) [mm]
        Y = {};   % y position of the ball (height) [mm]
        Z = {};   % z position of the ball (depth) [mm]

        xLeft
        yLeft
        xRight
        yRight

        % image processing variables
        isFrame = 0;
        threshold = .3;   % binarization threshold
        minSize = 50;     % minimum size[px] of detected objects
        se = strel('disk',3);  % structure element to fill gaps in image
        currentFrame = 1;   % current frame can be between 1 and 21 inclusive

        leftImage = {};
        rightImage = {};
        leftFrame = {};
        rightFrame = {};
        leftCentroid = {};
        rightCentroid = {};
    end


    methods (Access = private) 
        
        % convert image to grayscale, then black and white
        % remove small objects, close gaps, and fill holes
        % return edited image and roundest object's centroid 
        function [frame,xCentroid,yCentroid] = processImage(app,frame)

            frame = rgb2gray(frame);                 % convert to grayscale
            frame = imbinarize(frame,app.threshold); % convert to black and white
            frame = bwareaopen(frame,app.minSize);   % remove small objects
            frame = imclose(frame,app.se);           % close small gaps in boundaries
            frame = imfill(frame,'holes');           % fill holes in objects
            [B,L] = bwboundaries(frame,"noholes");   % find ext. object boundaries
            
            % determine the circularity and centroid of each object
            stats = regionprops(L,'Circularity','Centroid');

            % determine the roundest object and store its centroid
            roundestObj = 0;
            for k = 1:length(B)
                if stats(k).Circularity > roundestObj
                    roundestObj = stats(k).Circularity;
                    roundestCnt = k;
                end            
                xCentroid = stats(roundestCnt).Centroid(1,1)
                yCentroid = stats(roundestCnt).Centroid(1,2)
            end
        end


        % plot a processed image to an axis
        % draw a boundary around/display circularity of all objects
        % plot the centroid of the roundest object
        function plotObjects(app,frame,axis)

            [B,L] = bwboundaries(frame,"noholes");   % find ext. object boundaries

            % determine the circularity and centroid of each object
            stats = regionprops(L,'Circularity','Centroid');

            roundestObj = 0;
            for k = 1:length(B)

                boundary = B{k};   % get (x,y) boundary coordinates for label 'k'
                circ_value = stats(k).Circularity;   % obtain circularity for label 'k'
            
                imagesc(axis,label2rgb(L,@jet,[.5 .5 .5]))
                hold(axis,'on')
                for objects = 1:length(B)
                  boundary = B{objects};
                  plot(axis,boundary(:,2),boundary(:,1),"w",LineWidth=2)
                end
            
                % Display the circularity results
                circ_string = sprintf('%2.2f',circ_value);
                text(axis,boundary(1,2)-35,boundary(1,1)+13,circ_string,Color='y', ...
                    FontSize=14,FontWeight='bold');
            
                % determine the roundest object and store centroid
                if stats(k).Circularity > roundestObj
                    roundestObj = stats(k).Circularity;
                    roundestCnt = k;
                end
            
                centroid = stats(roundestCnt).Centroid
                plot(axis,centroid(1),centroid(2),'ko');
            end
        end


        % update the calculated and actual distances of the sphere
        function updateDistance(app)
            app.LabelCalcDist.Text = num2str(app.Z(app.currentFrame)/1000);
            actDist = (app.currentFrame-1)*.1 +3;
            app.LabelActDist.Text = num2str(actDist);
        end


        % plot the accuracy on the below UIAccuracy axes
        function plotAccuracy(app)
            xNumPix = 752;         % total num pixels in x direction of sensor [px]
            yNumPix = 480;         % total num pixels in y direction of sensor [px]
            cxLeft = xNumPix/2;    % left camera x center [px]
            cyLeft = yNumPix/2;    % left camera y center [px]
            cxRight = xNumPix/2;   % right camera x center [px]
            
            % Calculate depth
            app.d = (abs((app.xLeft-cxLeft)-(app.xRight-cxRight))*app.ps)   % disparity [mm]
            app.Z = (app.b*app.f)./app.d                            % depth [mm]
            app.X = app.Z .* ((app.xLeft-cxLeft) * app.ps)/app.f        % length [mm]
            app.Y = app.Z .* ((app.yLeft-cyLeft) * app.ps)/app.f        % height [mm]

            % plot the distance vs accuracy from 3 to 5 meters away
            dist = 3:.1:5;                              % actual distance [m]
            calcDist = app.Z/1000;                      % calculated dist. [m] 
            accuracy = 100-(calcDist-dist)./dist*100    % dist. accuracy [%]
            plot(app.UIAccuracy,dist,accuracy);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % axis(app.UIAxesLeft,'off')
            % axis(app.UIAxesRight,'off')
            % axis(app.UIAxesLeft,'tight')
            % axis(app.UIAxesRight,'tight')
        end

        % Value changed function: SelectFrameEditField
        function SelectFrameEditFieldValueChanged(app, event)
            app.currentFrame = app.SelectFrameEditField.Value;

            if app.isFrame == 0
                imagesc(app.UIAxesLeft,app.leftImage{app.currentFrame});
                imagesc(app.UIAxesRight,app.rightImage{app.currentFrame});
            else
                app.plotObjects(app.leftFrame{app.currentFrame},app.UIAxesLeft);
                app.plotObjects(app.rightFrame{app.currentFrame},app.UIAxesRight);
            end

            app.updateDistance();
        end

        % Button pushed function: BlenderLinkButton
        function BlenderLinkButtonPushed(app, event)
            client = tcpclient(app.server_ip,app.server_port,"Timeout",20);
            fprintf(1,"Connected to server\n");
        
            % resolution of virtual camera
            width = 752;
            height = 480;
            
            % generate images of ball(r=1m) from 3 to 5 meters away
            % distance is from the ball's surface to the camera's origin
            for counter = 1:21
        
                % centroid iterates from Z=0m to Z=-2m
                ballLocation = (counter-1)*-.1; 
                blenderLink(client,width,height,0,0,ballLocation,0,0,0,'Sphere');
        
                subplot(1,2,1)
                app.leftImage{counter} = blenderLink(client,width,height,-.03,0,4,0,0,0,'Camera');
                imagesc(app.UIAxesLeft,app.leftImage{counter});
                %set(app.UIAxesLeft, gcf, 'Position', get(0,'Screensize'));
                axis(app.UIAxesLeft,'off')
                axis(app.UIAxesLeft,'tight')
                [app.leftFrame{counter},app.xLeft(counter),app.yLeft(counter)] = app.processImage(app.leftImage{counter});
            
                subplot(1,2,2)
                app.rightImage{counter} = blenderLink(client,width,height,.03,0,4,0,0,0,'Camera');
                imagesc(app.UIAxesRight,app.rightImage{counter});
                %set(app.UIAxesRight, gcf, 'Position', get(0,'Screensize'));
                axis(app.UIAxesRight,'off')
                axis(app.UIAxesRight,'tight')
                [app.rightFrame{counter},app.xRight(counter),app.yRight(counter)] = app.processImage(app.rightImage{counter});
            end
        
            leftImage = app.leftImage;
            rightImage = app.rightImage;

            save Lab4Images leftImage rightImage
        end

        % Button pushed function: LoadmatButton
        function LoadmatButtonPushed(app, event)
            load Lab4Images.mat leftImage rightImage
            app.leftImage = leftImage;
            app.rightImage = rightImage;

            imagesc(app.UIAxesLeft,app.leftImage{1});
            imagesc(app.UIAxesRight,app.rightImage{1});
            axis(app.UIAxesLeft,'off')
            axis(app.UIAxesLeft,'tight')
            axis(app.UIAxesRight,'off')
            axis(app.UIAxesRight,'tight')

            for counter = 1:21
                [app.leftFrame{counter},app.xLeft(counter),app.yLeft(counter)] = ...
                    app.processImage(app.leftImage{counter});
                [app.rightFrame{counter},app.xRight(counter),app.yRight(counter)] = ...
                    app.processImage(app.rightImage{counter});
            end
            app.plotAccuracy();
            app.updateDistance();
        end

        % Value changed function: SwitchViewingModeRawProcessedButton
        function SwitchViewingModeRawProcessedButtonValueChanged(app, event)
            app.isFrame = app.SwitchViewingModeRawProcessedButton.Value;

            if app.isFrame == 0
                imagesc(app.UIAxesLeft,app.leftImage{app.currentFrame});
                imagesc(app.UIAxesRight,app.rightImage{app.currentFrame});
            else
                app.plotObjects(app.leftFrame{app.currentFrame},app.UIAxesLeft);
                app.plotObjects(app.rightFrame{app.currentFrame},app.UIAxesRight);
            end

            app.updateDistance();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesLeft = uiaxes(app.UIFigure);
            title(app.UIAxesLeft, 'Left Camera')
            app.UIAxesLeft.XTick = [];
            app.UIAxesLeft.YTick = [];
            app.UIAxesLeft.YTickLabel = '';
            app.UIAxesRight = uiaxes(app.UIFigure);
            title(app.UIAxesRight, 'Right Camera')
            app.UIAxesRight.XTick = [];
            app.UIAxesRight.YTick = [];
            app.CenterPxLLabel = uilabel(app.UIFigure);
            app.CenterPxLLabel.Text = 'Center Px (x,y):';
            app.xPxLLabel = uilabel(app.UIFigure);
            app.xPxLLabel.Text = 'xPxL';
            app.yPxLLabel = uilabel(app.UIFigure);
            app.yPxLLabel.Text = 'yPxL';
            app.CenterPxRLabel = uilabel(app.UIFigure);
            app.CenterPxRLabel.Text = 'Center Px (x,y):';
            app.xPxRLabel = uilabel(app.UIFigure);
            app.xPxRLabel.Text = 'xPxR';
            app.yPxRLabel = uilabel(app.UIFigure);
            app.yPxRLabel.Text = 'yPxR';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.InstantReplayButton = uibutton(app.UIFigure, 'push');
            app.InstantReplayButton.Text = 'Instant Replay';
            app.SaveRecordingButton = uibutton(app.UIFigure, 'push');
            app.SaveRecordingButton.Text = 'Save Recording';
            app.XlengthLabel = uilabel(app.UIFigure);
            app.XlengthLabel.Text = 'X (length):';
            app.xPosLabel = uilabel(app.UIFigure);
            app.xPosLabel.Text = 'xPos';
            app.ViewingModeLabel = uilabel(app.UIFigure);
            app.ViewingModeLabel.Text = 'Viewing Mode:';
            app.ViewModeLabel = uilabel(app.UIFigure);
            app.ViewModeLabel.Text = 'ViewMode';
            app.YwidthLabel = uilabel(app.UIFigure);
            app.YwidthLabel.Text = 'Y (width):';
            app.yPosLabel = uilabel(app.UIFigure);
            app.yPosLabel.Text = 'yPos';
            app.SwitchViewingModeRawProcessedButton = uibutton(app.UIFigure, 'state');
            app.SwitchViewingModeRawProcessedButton.ValueChangedFcn = createCallbackFcn(app, @SwitchViewingModeRawProcessedButtonValueChanged, true);
            app.SwitchViewingModeRawProcessedButton.Text = {'Switch Viewing Mode'; '[Raw/Processed]'};
            app.ZheightLabel = uilabel(app.UIFigure);
            app.ZheightLabel.Text = 'Z (height):';
            app.zPosLabel = uilabel(app.UIFigure);
            app.zPosLabel.Text = 'zPos';
            app.inBoundsLEDLabel = uilabel(app.UIFigure);
            app.inBoundsLEDLabel.Text = 'inBoundsLED';
            app.inBoundsLabel = uilabel(app.UIFigure);
            app.inBoundsLabel.Text = 'Ball is currently in bounds';
            app.SelectFrame59Label = uilabel(app.UIFigure);
            app.SelectFrame59Label.Text = {'Select Frame'; '[1 - 21]'; 'feature from lab 4 that i'; 'will convert to the select spinners'};
            app.SelectFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectFrameEditField.Limits = [1 21];
            app.SelectFrameEditField.ValueChangedFcn = createCallbackFcn(app, @SelectFrameEditFieldValueChanged, true);
            app.SelectFrameEditField.Placeholder = '1 - 21';
            app.SelectFrameEditField.Value = 1;
            app.SettingsColumnPanel = uipanel(app.UIFigure);
            app.SettingsColumnPanel.Title = 'Settings Column';
            app.CourtColorLabel = uilabel(app.SettingsColumnPanel);
            app.CourtColorLabel.Text = 'Court Color:';
            app.CourtColorColorPicker = uicolorpicker(app.SettingsColumnPanel);
            app.BallColorLabel = uilabel(app.SettingsColumnPanel);
            app.BallColorLabel.Text = 'Ball Color:';
            app.BallColorColorPicker = uicolorpicker(app.SettingsColumnPanel);
            app.CameraConfigurationButton = uibutton(app.SettingsColumnPanel, 'push');
            app.CameraConfigurationButton.Text = 'Camera Configuration';
            app.CalibrateCamButton = uibutton(app.SettingsColumnPanel, 'push');
            app.CalibrateCamButton.Text = 'Calibrate Cam?';
            app.DepthAccuracyGraphButton = uibutton(app.SettingsColumnPanel, 'push');
            app.DepthAccuracyGraphButton.Text = 'Depth Accuracy Graph';
            app.ShiftAccuracyGraphButton = uibutton(app.SettingsColumnPanel, 'push');
            app.ShiftAccuracyGraphButton.Text = 'Shift Accuracy Graph';
            app.SelectServeLabel = uilabel(app.SettingsColumnPanel);
            app.SelectServeLabel.Text = 'Select Serve:';
            app.SelectServeSpinner = uispinner(app.SettingsColumnPanel);
            app.SelectServeSpinner.Limits = [0 5];
            app.SelectServeSpinner.RoundFractionalValues = 'on';
            app.SelectReturnLabel = uilabel(app.SettingsColumnPanel);
            app.SelectReturnLabel.Text = 'Select Return:';
            app.SelectReturnSpinner = uispinner(app.SettingsColumnPanel);
            app.SelectReturnSpinner.Limits = [0 5];
            app.SelectReturnSpinner.RoundFractionalValues = 'on';
            app.BlenderLinkButton = uibutton(app.SettingsColumnPanel, 'push');
            app.BlenderLinkButton.ButtonPushedFcn = createCallbackFcn(app, @BlenderLinkButtonPushed, true);
            app.BlenderLinkButton.Text = 'Blender Link';
            app.LoadmatButton = uibutton(app.SettingsColumnPanel, 'push');
            app.LoadmatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmatButtonPushed, true);
            app.LoadmatButton.Text = 'Load .mat';
            app.xPosLabel_2 = uilabel(app.UIFigure);
            app.xPosLabel_2.Text = 'xPos';
            app.yPosLabel_2 = uilabel(app.UIFigure);
            app.yPosLabel_2.Text = 'yPos';
            app.zPosLabel_2 = uilabel(app.UIFigure);
            app.zPosLabel_2.Text = 'zPos';
            app.CalculatedLabel = uilabel(app.UIFigure);
            app.CalculatedLabel.Text = 'Calculated';
            app.ActualLabel = uilabel(app.UIFigure);
            app.ActualLabel.Text = 'Actual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cdr_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end